classdef FC_Design_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ExperimentIDEditFieldLabel      matlab.ui.control.Label
        ExperimentIDEditField           matlab.ui.control.EditField
        CSDropDownLabel                 matlab.ui.control.Label
        CSDropDown                      matlab.ui.control.DropDown
        CSDropDown_2Label               matlab.ui.control.Label
        CSDropDown_2                    matlab.ui.control.DropDown
        BaselinesEditFieldLabel         matlab.ui.control.Label
        BaselinesEditField              matlab.ui.control.NumericEditField
        CSdurationsEditFieldLabel       matlab.ui.control.Label
        CSdurationsEditField            matlab.ui.control.NumericEditField
        InterCSintervalminEditFieldLabel  matlab.ui.control.Label
        InterCSintervalminEditField     matlab.ui.control.NumericEditField
        InterCSintervalmaxEditFieldLabel  matlab.ui.control.Label
        InterCSintervalmaxEditField     matlab.ui.control.NumericEditField
        ShockdurationsEditFieldLabel    matlab.ui.control.Label
        ShockdurationsEditField         matlab.ui.control.NumericEditField
        TonefreqHzEditFieldLabel        matlab.ui.control.Label
        TonefreqHzEditField             matlab.ui.control.NumericEditField
        FMSweepstartHzEditFieldLabel    matlab.ui.control.Label
        FMSweepstartHzEditField         matlab.ui.control.NumericEditField
        FMSweependHzEditFieldLabel      matlab.ui.control.Label
        FMSweependHzEditField           matlab.ui.control.NumericEditField
        FMSweepdursEditFieldLabel       matlab.ui.control.Label
        FMSweepdursEditField            matlab.ui.control.NumericEditField
        LightpulsefreqHzEditFieldLabel  matlab.ui.control.Label
        LightpulsefreqHzEditField       matlab.ui.control.NumericEditField
        LightpulsedutycycleEditFieldLabel  matlab.ui.control.Label
        LightpulsedutycycleEditField    matlab.ui.control.NumericEditField
        FearConditioningExperimentDesignerCSandUSParamsLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        WithshockButtonGroup            matlab.ui.container.ButtonGroup
        NoshockButton_2                 matlab.ui.control.ToggleButton
        YesshockButton                  matlab.ui.control.ToggleButton
        WithlaserButtonGroup            matlab.ui.container.ButtonGroup
        NolaserButton                   matlab.ui.control.ToggleButton
        YeslaserButton                  matlab.ui.control.ToggleButton
        AddeventButton                  matlab.ui.control.Button
        ChooseCSButtonGroup             matlab.ui.container.ButtonGroup
        CSButton                        matlab.ui.control.ToggleButton
        CSButton_2                      matlab.ui.control.ToggleButton
        BuildExperimentLabel            matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        P = struct; % params struct
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ExperimentIDEditField
        function ExperimentIDEditFieldValueChanged(app, event)
            value = app.ExperimentIDEditField.Value;
            app.P.exp_ID = value;
        end

        % Value changed function: CSDropDown
        function CSDropDownValueChanged(app, event)
            value = app.CSDropDown.Value;
            app.P.cs_plus = value;
        end

        % Value changed function: CSDropDown_2
        function CSDropDown_2ValueChanged(app, event)
            value = app.CSDropDown_2.Value;
            app.P.cs_minus = value;
        end

        % Value changed function: BaselinesEditField
        function BaselinesEditFieldValueChanged(app, event)
            value = app.BaselinesEditField.Value;
            app.P.t_baseline = value;
        end

        % Value changed function: CSdurationsEditField
        function CSdurationsEditFieldValueChanged(app, event)
            value = app.CSdurationsEditField.Value;
            app.P.cs_dur = value;
        end

        % Value changed function: InterCSintervalminEditField
        function InterCSintervalminEditFieldValueChanged(app, event)
            value = app.InterCSintervalminEditField.Value;
            app.P.min_trial_int = value;
        end

        % Value changed function: InterCSintervalmaxEditField
        function InterCSintervalmaxEditFieldValueChanged(app, event)
            value = app.InterCSintervalmaxEditField.Value;
            app.P.max_trial_int = value;
        end

        % Value changed function: ShockdurationsEditField
        function ShockdurationsEditFieldValueChanged(app, event)
            value = app.ShockdurationsEditField.Value;
            app.P.us_dur = value;
        end

        % Selection changed function: ChooseCSButtonGroup
        function ChooseCSButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseCSButtonGroup.SelectedObject;
            app.P.this_cs = selectedButton;
        end

        % Value changed function: TonefreqHzEditField
        function TonefreqHzEditFieldValueChanged(app, event)
            value = app.TonefreqHzEditField.Value;
            app.P.tone_freq = value;
        end

        % Value changed function: FMSweepstartHzEditField
        function FMSweepstartHzEditFieldValueChanged(app, event)
            value = app.FMSweepstartHzEditField.Value;
            app.P.start_freq = value;
        end

        % Value changed function: FMSweependHzEditField
        function FMSweependHzEditFieldValueChanged(app, event)
            value = app.FMSweependHzEditField.Value;
            app.P.end_freq = value;
        end

        % Value changed function: FMSweepdursEditField
        function FMSweepdursEditFieldValueChanged(app, event)
            value = app.FMSweepdursEditField.Value;
            app.P.sweep_dur = value;
        end

        % Value changed function: LightpulsefreqHzEditField
        function LightpulsefreqHzEditFieldValueChanged(app, event)
            value = app.LightpulsefreqHzEditField.Value;
            app.P.flicker_freq = value;
        end

        % Value changed function: LightpulsedutycycleEditField
        function LightpulsedutycycleEditFieldValueChanged(app, event)
            value = app.LightpulsedutycycleEditField.Value;
            app.P.light_dc = value;
        end

        % Selection changed function: WithshockButtonGroup
        function WithshockButtonGroupSelectionChanged(app, event)
            selectedButton = app.WithshockButtonGroup.SelectedObject;
            app.P.this_shock = selectedButton;
        end

        % Selection changed function: WithlaserButtonGroup
        function WithlaserButtonGroupSelectionChanged(app, event)
            selectedButton = app.WithlaserButtonGroup.SelectedObject;
            app.P.this_laser = selectedButton;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {531, 531};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {273, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExperimentIDEditFieldLabel = uilabel(app.LeftPanel);
            app.ExperimentIDEditFieldLabel.Text = 'Experiment ID';
            app.ExperimentIDEditField = uieditfield(app.LeftPanel, 'text');
            app.ExperimentIDEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentIDEditFieldValueChanged, true);
            app.CSDropDownLabel = uilabel(app.LeftPanel);
            app.CSDropDownLabel.Text = 'CS +';
            app.CSDropDown = uidropdown(app.LeftPanel);
            app.CSDropDown.Items = {'Tone', 'FM Sweep', 'Light', 'Pulsed Light'};
            app.CSDropDown.ValueChangedFcn = createCallbackFcn(app, @CSDropDownValueChanged, true);
            app.CSDropDown.Value = 'Tone';
            app.CSDropDown_2Label = uilabel(app.LeftPanel);
            app.CSDropDown_2Label.Text = 'CS -';
            app.CSDropDown_2 = uidropdown(app.LeftPanel);
            app.CSDropDown_2.Items = {'Tone', 'FM Sweep', 'Light', 'Pulsed Light'};
            app.CSDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CSDropDown_2ValueChanged, true);
            app.CSDropDown_2.Value = 'Tone';
            app.BaselinesEditFieldLabel = uilabel(app.LeftPanel);
            app.BaselinesEditFieldLabel.Text = 'Baseline (s)';
            app.BaselinesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BaselinesEditField.ValueChangedFcn = createCallbackFcn(app, @BaselinesEditFieldValueChanged, true);
            app.CSdurationsEditFieldLabel = uilabel(app.LeftPanel);
            app.CSdurationsEditFieldLabel.Text = 'CS duration (s)';
            app.CSdurationsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CSdurationsEditField.ValueChangedFcn = createCallbackFcn(app, @CSdurationsEditFieldValueChanged, true);
            app.InterCSintervalminEditFieldLabel = uilabel(app.LeftPanel);
            app.InterCSintervalminEditFieldLabel.Text = 'Inter-CS interval min';
            app.InterCSintervalminEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InterCSintervalminEditField.ValueChangedFcn = createCallbackFcn(app, @InterCSintervalminEditFieldValueChanged, true);
            app.InterCSintervalmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.InterCSintervalmaxEditFieldLabel.Text = 'Inter-CS interval max';
            app.InterCSintervalmaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InterCSintervalmaxEditField.ValueChangedFcn = createCallbackFcn(app, @InterCSintervalmaxEditFieldValueChanged, true);
            app.ShockdurationsEditFieldLabel = uilabel(app.LeftPanel);
            app.ShockdurationsEditFieldLabel.Text = 'Shock duration (s)';
            app.ShockdurationsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ShockdurationsEditField.ValueChangedFcn = createCallbackFcn(app, @ShockdurationsEditFieldValueChanged, true);
            app.TonefreqHzEditFieldLabel = uilabel(app.LeftPanel);
            app.TonefreqHzEditFieldLabel.Text = 'Tone freq (Hz)';
            app.TonefreqHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TonefreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @TonefreqHzEditFieldValueChanged, true);
            app.FMSweepstartHzEditFieldLabel = uilabel(app.LeftPanel);
            app.FMSweepstartHzEditFieldLabel.Text = 'FM Sweep start (Hz)';
            app.FMSweepstartHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FMSweepstartHzEditField.ValueChangedFcn = createCallbackFcn(app, @FMSweepstartHzEditFieldValueChanged, true);
            app.FMSweependHzEditFieldLabel = uilabel(app.LeftPanel);
            app.FMSweependHzEditFieldLabel.Text = 'FM Sweep end (Hz)';
            app.FMSweependHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FMSweependHzEditField.ValueChangedFcn = createCallbackFcn(app, @FMSweependHzEditFieldValueChanged, true);
            app.FMSweepdursEditFieldLabel = uilabel(app.LeftPanel);
            app.FMSweepdursEditFieldLabel.Text = 'FM Sweep dur (s)';
            app.FMSweepdursEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FMSweepdursEditField.ValueChangedFcn = createCallbackFcn(app, @FMSweepdursEditFieldValueChanged, true);
            app.LightpulsefreqHzEditFieldLabel = uilabel(app.LeftPanel);
            app.LightpulsefreqHzEditFieldLabel.Text = 'Light pulse freq (Hz)';
            app.LightpulsefreqHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LightpulsefreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @LightpulsefreqHzEditFieldValueChanged, true);
            app.LightpulsedutycycleEditFieldLabel = uilabel(app.LeftPanel);
            app.LightpulsedutycycleEditFieldLabel.Text = 'Light pulse duty cycle';
            app.LightpulsedutycycleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LightpulsedutycycleEditField.ValueChangedFcn = createCallbackFcn(app, @LightpulsedutycycleEditFieldValueChanged, true);
            app.FearConditioningExperimentDesignerCSandUSParamsLabel = uilabel(app.LeftPanel);
            app.FearConditioningExperimentDesignerCSandUSParamsLabel.Text = {'Fear Conditioning Experiment Designer'; 'CS and US Params'};
            app.RightPanel = uipanel(app.GridLayout);
            app.WithshockButtonGroup = uibuttongroup(app.RightPanel);
            app.WithshockButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WithshockButtonGroupSelectionChanged, true);
            app.WithshockButtonGroup.Title = 'With shock?';
            app.NoshockButton_2 = uitogglebutton(app.WithshockButtonGroup);
            app.NoshockButton_2.Text = 'No shock';
            app.NoshockButton_2.Value = true;
            app.YesshockButton = uitogglebutton(app.WithshockButtonGroup);
            app.YesshockButton.Text = 'Yes shock';
            app.WithlaserButtonGroup = uibuttongroup(app.RightPanel);
            app.WithlaserButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WithlaserButtonGroupSelectionChanged, true);
            app.WithlaserButtonGroup.Title = 'With laser?';
            app.NolaserButton = uitogglebutton(app.WithlaserButtonGroup);
            app.NolaserButton.Text = 'No laser';
            app.YeslaserButton = uitogglebutton(app.WithlaserButtonGroup);
            app.YeslaserButton.Text = 'Yes laser';
            app.AddeventButton = uibutton(app.RightPanel, 'push');
            app.AddeventButton.Text = 'Add event';
            app.ChooseCSButtonGroup = uibuttongroup(app.RightPanel);
            app.ChooseCSButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseCSButtonGroupSelectionChanged, true);
            app.ChooseCSButtonGroup.Title = 'Choose CS';
            app.CSButton = uitogglebutton(app.ChooseCSButtonGroup);
            app.CSButton.Text = 'CS+';
            app.CSButton.Value = true;
            app.CSButton_2 = uitogglebutton(app.ChooseCSButtonGroup);
            app.CSButton_2.Text = 'CS-';
            app.BuildExperimentLabel = uilabel(app.RightPanel);
            app.BuildExperimentLabel.Text = 'Build Experiment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FC_Design_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end