classdef CellScreenerNaren < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        vaxe                        matlab.ui.control.UIAxes
        taxe1                       matlab.ui.control.UIAxes
        taxe2                       matlab.ui.control.UIAxes
        vaxe2                       matlab.ui.control.UIAxes
        LoadVmataviButton           matlab.ui.control.Button
        LoadmsmatButton             matlab.ui.control.Button
        FrameSliderLabel            matlab.ui.control.Label
        FrameSlider                 matlab.ui.control.Slider
        ImagetypeDropDownLabel      matlab.ui.control.Label
        ImagetypeDropDown           matlab.ui.control.DropDown
        Celllist1ListBoxLabel       matlab.ui.control.Label
        CelllistListBox1            matlab.ui.control.ListBox
        GoodLabel1                  matlab.ui.control.Label
        stepEditFieldLabel          matlab.ui.control.Label
        stepEditField               matlab.ui.control.NumericEditField
        SaveButton                  matlab.ui.control.Button
        PlayButton                  matlab.ui.control.StateButton
        ChillingLabel               matlab.ui.control.Label
        TracetypeDropDownLabel      matlab.ui.control.Label
        TracetypeDropDown           matlab.ui.control.DropDown
        ContourThrEditFieldLabel    matlab.ui.control.Label
        ContourThrEditField         matlab.ui.control.NumericEditField
        Sort_ROIDistButton          matlab.ui.control.Button
        Corr2Label                  matlab.ui.control.Label
        Overlap0Label               matlab.ui.control.Label
        CelllistListBoxLabel_2      matlab.ui.control.Label
        CelllistListBox2            matlab.ui.control.ListBox
        GoodLabel2                  matlab.ui.control.Label
        Trace1Label                 matlab.ui.control.Label
        Trace2Label                 matlab.ui.control.Label
        LockContourCheckBox         matlab.ui.control.CheckBox
        PathLabel                   matlab.ui.control.Label
        RasterVideoButton           matlab.ui.control.Button
        Goodcount0Label             matlab.ui.control.Label
        Badcount0Label              matlab.ui.control.Label
        ShowbadcellsCheckBox        matlab.ui.control.CheckBox
        ImagetypeDropDown_2         matlab.ui.control.DropDown
        ShowgoodcellsCheckBox       matlab.ui.control.CheckBox
        Sort_ROIValButton           matlab.ui.control.Button
        Frame1Label                 matlab.ui.control.Label
        ROITraceButton              matlab.ui.control.Button
        ClearmsButton               matlab.ui.control.Button
        AutozoomEditFieldLabel      matlab.ui.control.Label
        AutozoomEditField           matlab.ui.control.NumericEditField
        KeyPressFcnSsortbydistanceBLabel  matlab.ui.control.Label
        ShowunlabeledcellsCheckBox  matlab.ui.control.CheckBox
        Unlabeledcount0Label        matlab.ui.control.Label
        MaxTraceThrEditFieldLabel   matlab.ui.control.Label
        MaxTraceThrEditField        matlab.ui.control.NumericEditField
    end

    % comit
    properties (Access = public)
        vmat; % video mat, matfile obj
        maxproj; % maxprojection image
        df_maxproj;
        fn; % video mat field name
        ms; % cnmfe output mat
        fr = 15; % frame rate
        or_size; % original size
        A_hd; % upsampled hd contour
        curr_cell1 = 1;
        curr_frame = 1;
        cell_label = []; % 1 = good, 0 = bad
        cell_reviewed = []; % 1 = visited, 0 = unvisited;
        phandles; % various plot handles
        bg_f; % background frame for calculate df
        t_ax = [];
        corr = [];
        overlap = [];
        contours_h = {}; % contour handle in vaxe 2;
        curr_cell2 = 1; % cell shown in plot 2;
        centroids = []; % centroid coordinate of each roi
        dist_map = []; % distance map
        rawcorr_map = []; % correlation map raw
        overlap_map = [];% overlap map
        filtcorr_map = []; % correlation map filtered
        num_c = 0; % number of cells
        ms_path = ''; % Description
        g_count = 0;
        b_count = 0;
        u_count = 0;
    end
    
    properties (Access = private)
        ori_xlim = []; % original x lim of video ax 1
        ori_ylim = []; % original y lim of video ax 1
    end

    
    methods (Access = private)
        
        function updatedf(app, fvalue)
            if nargin < 2
                fvalue = app.curr_frame;
            end
            
%             bg_f = mean(double(app.vmat(:,:,max(fvalue-261, 1):fvalue-61)), 3);
            current_f = double(app.vmat(:,:,fvalue));
            df_frame_rgb = repmat((current_f - app.bg_f)./app.bg_f, [1,1,3]);
%             df_frame_rgb = (current_f - app.bg_f)./app.bg_f;
%             imshow(df_frame_rgb, 'Parent', app.vaxe); 
            if isfield(app.phandles, 'vhandle')
                set(app.phandles.vhandle,'CData', df_frame_rgb);
            else
                app.phandles.vhandle = imshow(df_frame_rgb,'Parent',app.vaxe);
            end
        end

        
        function updatedf2(app, fvalue)
            if nargin < 2
                fvalue = app.curr_frame;
            end
            
%             bg_f = mean(double(app.vmat(:,:,max(fvalue-261, 1):fvalue-61)), 3);
            current_f = double(app.vmat(:,:,fvalue));
            df_frame_rgb = repmat((current_f - app.bg_f)./app.bg_f, [1,1,3]);
%             df_frame_rgb = (current_f - app.bg_f)./app.bg_f;
%             imshow(df_frame_rgb, 'Parent', app.vaxe); 
            if isfield(app.phandles, 'vax2maxproj')
                set(app.phandles.vax2maxproj,'CData', df_frame_rgb);
            else
                app.phandles.vax2maxproj = imshow(df_frame_rgb,'Parent',app.vaxe);
            end
        end
        
        
        function updateraw(app, fvalue)
            if nargin < 2
                fvalue = app.curr_frame;
            end
%             imshow(app.vmat(:,:,app.curr_frame),'Parent',app.vaxe);
            if isfield(app.phandles, 'vhandle')
                set(app.phandles.vhandle,'CData', app.vmat(:,:,fvalue));
            else
                app.phandles.vhandle = imshow(app.vmat(:,:,fvalue),'Parent',app.vaxe);
            end
        end
        
        function updateraw2(app, fvalue)
            if nargin < 2
                fvalue = app.curr_frame;
            end
%             imshow(app.vmat(:,:,app.curr_frame),'Parent',app.vaxe);
            if isfield(app.phandles, 'vax2maxproj')
                set(app.phandles.vax2maxproj,'CData', app.vmat(:,:,fvalue));
            else
                app.phandles.vax2maxproj = imshow(app.vmat(:,:,fvalue),'Parent',app.vaxe2);
            end
        end
        
        function updatelabel1(app)
            if app.cell_reviewed(app.curr_cell1) == 0 & ~strcmp(app.GoodLabel1.Text, 'Unlabeled')
                   app.GoodLabel1.FontColor = [1 1 1];
                   app.GoodLabel1.Text = 'Unlabeled';
            elseif app.cell_reviewed(app.curr_cell1) == 1 & app.cell_label(app.curr_cell1) == 1 & ~strcmp(app.GoodLabel1.Text, 'Good')
                   app.GoodLabel1.FontColor = [0.3922 0.8314 0.0745];
                   app.GoodLabel1.Text = 'Good';
                
            elseif app.cell_reviewed(app.curr_cell1) == 1 & app.cell_label(app.curr_cell1) == 0 & ~strcmp(app.GoodLabel1.Text, 'Bad')
                   app.GoodLabel1.FontColor = [0.8314 0.3922 0.0745];
                   app.GoodLabel1.Text = 'Bad';
            end
            
        end
        
        function updatelabel2(app)
            if app.cell_reviewed(app.curr_cell2) == 0 & ~strcmp(app.GoodLabel2.Text, 'Unlabeled')
                   app.GoodLabel2.FontColor = [1 1 1];
                   app.GoodLabel2.Text = 'Unlabeled';
            elseif app.cell_reviewed(app.curr_cell2) == 1 & app.cell_label(app.curr_cell2) == 1 & ~strcmp(app.GoodLabel2.Text, 'Good')
                   app.GoodLabel2.FontColor = [0.3922 0.8314 0.0745];
                   app.GoodLabel2.Text = 'Good';
                
            elseif app.cell_reviewed(app.curr_cell2) == 1 & app.cell_label(app.curr_cell2) == 0 & ~strcmp(app.GoodLabel2.Text, 'Bad')
                   app.GoodLabel2.FontColor = [0.8314 0.3922 0.0745];
                   app.GoodLabel2.Text = 'Bad';
            end
            
        end        
        
        
        function updatems(app)

                updatetrace1(app);
                updatetrace2(app);
                updateline1(app);
                updateline2(app);
                updatecontour1(app);
                updatecontour2(app);

                drawnow;
            
        end
        
        function updateline1(app, fvalue)
            if nargin < 2
                fvalue = app.curr_frame;
            end
            if isfield(app.phandles, 'lhandle1')
                set(app.phandles.lhandle1,'XData', [fvalue/app.fr, fvalue/app.fr], 'YData', app.taxe1.YLim);
            else

                app.phandles.lhandle1 = line(app.taxe1, [fvalue/app.fr, fvalue/app.fr], app.taxe1.YLim, 'color', 'red'); 

            end
        end
        function updatetrace1(app)
            % erase line 1 first to reset y lim
            if isfield(app.phandles, 'lhandle1')
                set(app.phandles.lhandle1, 'XData', [], 'YData', []);
            end
            if isfield(app.phandles, 'thandle1')
                if strcmp(app.TracetypeDropDown.Value, 'Raw')
                    set(app.phandles.thandle1, 'YData', app.ms.RawTraces(:, app.curr_cell1(1)));
                elseif strcmp(app.TracetypeDropDown.Value, 'Filtered')
                    set(app.phandles.thandle1, 'YData', app.ms.FiltTraces(:, app.curr_cell1(1)));
                else
                    set(app.phandles.thandle1, 'YData', app.ms.FFTTraces(:, app.curr_cell1(1)));
                end
            else
               if strcmp(app.TracetypeDropDown.Value, 'Raw')
                    app.phandles.thandle1 = plot(app.taxe1, app.t_ax, app.ms.RawTraces(:, app.curr_cell1(1)));
                    app.taxe1.XLim = [0, app.t_ax(end)];
               elseif strcmp(app.TracetypeDropDown.Value, 'Filtered')
                    app.phandles.thandle1 = plot(app.taxe1, app.t_ax, app.ms.FiltTraces(:, app.curr_cell1(1)));
                    app.taxe1.XLim = [0, app.t_ax(end)];
               else
                   app.phandles.thandle1 = plot(app.taxe1, app.t_ax, app.ms.FFTTraces(:, app.curr_cell1(1)));
                    app.taxe1.XLim = [0, app.t_ax(end)];
               end
            end
            updateline1(app);
        end
        
        function updateline2(app, fvalue)

                if nargin < 2
                    fvalue = app.curr_frame;
                end
                if isfield(app.phandles, 'lhandle2')
                    set(app.phandles.lhandle2,'XData', [fvalue/app.fr, fvalue/app.fr], 'YData', app.taxe2.YLim);
                else
    
                    app.phandles.lhandle2 = line(app.taxe2, [fvalue/app.fr, fvalue/app.fr], app.taxe2.YLim, 'color', 'red'); 
    
                end

        end
        
        function updatetrace2(app)
           if isfield(app.phandles, 'lhandle2')
               set(app.phandles.lhandle2, 'XData', [], 'Ydata', []);
           end
           if isfield(app.phandles, 'thandle2')
                if strcmp(app.TracetypeDropDown.Value, 'Raw')
                    set(app.phandles.thandle2, 'XData', app.t_ax, 'YData', app.ms.RawTraces(:, app.curr_cell2));
                elseif strcmp(app.TracetypeDropDown.Value, 'Filtered')
                    set(app.phandles.thandle2, 'XData', app.t_ax, 'YData', app.ms.FiltTraces(:, app.curr_cell2));
                else
                    set(app.phandles.thandle2, 'XData', app.t_ax, 'YData', app.ms.FFTTraces(:, app.curr_cell2));
                end
           else
               if strcmp(app.TracetypeDropDown.Value, 'Raw')
                    app.phandles.thandle2 = plot(app.taxe2, app.t_ax, app.ms.RawTraces(:, app.curr_cell2));
                    app.taxe2.XLim = [0, app.t_ax(end)];
               elseif strcmp(app.TracetypeDropDown.Value, 'Filtered')
                    app.phandles.thandle2 = plot(app.taxe2, app.t_ax, app.ms.FiltTraces(:, app.curr_cell2));
                    app.taxe2.XLim = [0, app.t_ax(end)];
               else 
                    app.phandles.thandle2 = plot(app.taxe2, app.t_ax, app.ms.FFTTraces(:, app.curr_cell2));
                    app.taxe2.XLim = [0, app.t_ax(end)];
           
               end
           end
           updateline2(app);

        end
        
        
        function updatecontour1(app)
            c_thre = app.ContourThrEditField.Value;
            curr_c = app.A_hd(:,:,app.curr_cell1);
            q = quantile(curr_c(curr_c > 0), c_thre);
            curr_c = single(curr_c >= q);
            if isfield(app.phandles, 'chandle1')
                set(app.phandles.chandle1, 'ZData', curr_c, 'Visible', 'on');
            else
                [~, app.phandles.chandle1] = contour(app.vaxe, curr_c, 'y');
            end
        end
        
        function updatecontour2(app)
            c_thre = app.ContourThrEditField.Value;
            curr_c = app.A_hd(:,:,app.curr_cell2);
            q = quantile(curr_c(curr_c > 0), c_thre);
            curr_c = single(curr_c >= q);
            if isfield(app.phandles, 'chandle2')
                set(app.phandles.chandle2, 'ZData', curr_c);
            else
                [~, app.phandles.chandle2] = contour(app.vaxe, curr_c, 'r');
            end
        end
        
        function C = ConCenter(app)
            
                C = zeros([size(app.A_hd, 3), 2]);
                [x_m, y_m] = meshgrid(1:size(app.A_hd,2), 1:size(app.A_hd,1)); % x mesh, dimension 2; y mesh dimension 1
                for j = 1:size(app.A_hd, 3)
                    
                    c_all = sum(app.A_hd(:,:,j), 'all');
                    x_c = round(sum(x_m .* app.A_hd(:,:,j) / c_all, 'all'));
                    y_c = round(sum(y_m .* app.A_hd(:,:,j) / c_all, 'all'));
                    C(j,:) = [x_c, y_c];
                    
                end


        end
        
        function dst = Dist(app, c1, c2)
            dst = sqrt(sum(c1 - c2).^2);
            
        end
        
        function updateCorr(app)
            switch app.TracetypeDropDown.Value
                case 'Raw'
                    if app.rawcorr_map(app.curr_cell1, app.curr_cell2) == -2
                        app.corr = corr2(app.ms.RawTraces(:,app.curr_cell1), app.ms.RawTraces(:,app.curr_cell2));
                        app.rawcorr_map(app.curr_cell1, app.curr_cell2) = app.corr;
                        app.rawcorr_map(app.curr_cell2, app.curr_cell1) = app.corr;
                    else
                        app.corr = app.rawcorr_map(app.curr_cell1, app.curr_cell2);
                    end
                case 'Filtered'
                    if app.filtcorr_map(app.curr_cell1, app.curr_cell2) == -2
                        app.corr = corr2(app.ms.FiltTraces(:,app.curr_cell1), app.ms.FiltTraces(:,app.curr_cell2));
                        app.filtcorr_map(app.curr_cell1, app.curr_cell2) = app.corr;
                        app.filtcorr_map(app.curr_cell2, app.curr_cell1) = app.corr;
                    else
                        app.corr = app.filtcorr_map(app.curr_cell1, app.curr_cell2);
                    end
            end
            app.Corr2Label.Text = sprintf('Corr: %.2f', app.corr);
            
        end
        function updateOverlap(app)
            c1 = app.A_hd(:,:,app.curr_cell1);
            c2 = app.A_hd(:,:,app.curr_cell2);
            if app.overlap_map(app.curr_cell1, app.curr_cell2) == -1
                overl = (c1.*c2)>0;
                app.overlap = (sum(c1(overl)) + sum(c2(overl)))/(sum(c1(:)) + sum(c2(:))); 
                app.overlap_map(app.curr_cell1, app.curr_cell2) = app.overlap;
                app.overlap_map(app.curr_cell2, app.curr_cell1) = app.overlap;
            else
                app.overlap = app.overlap_map(app.curr_cell1, app.curr_cell2);
            end
            
            app.Overlap0Label.Text = sprintf('Overlap: %.2f', app.overlap);
        end
        
        function RasterMo(app, params)
            RasterMo_XZ(app, params);
            
        end
        
        function updatecount(app)
            app.Goodcount0Label.Text = sprintf('Good count = %d', app.g_count);
            app.Badcount0Label.Text = sprintf('Bad count = %d', app.b_count);
            app.Unlabeledcount0Label.Text = sprintf('Unlabeled count = %d', app.u_count);
        end
        
        function ContourBttnDn(app, this_contour, ~)
            app.CelllistListBox1.Value = this_contour.Tag;
            CelllistListBox1ValueChanged(app, []);
            
        end
           
        function updateZoom(app)
            % get magnification
            mag = round(app.AutozoomEditField.Value);
            if mag ~= 1
                % find current centroid
                cur_c1 = app.curr_cell1;
                current_centroid = app.centroids(cur_c1, :);
                % calculate the new lim
                vsiz = size(app.vmat);
                xrange = vsiz(2);
                yrange = vsiz(1);
                new_xrange = xrange/mag;
                new_yrange = yrange/mag;
                newx = [current_centroid(1) - round(new_xrange/2), current_centroid(1) + round(new_xrange/2)]+0.5;
                newy = [current_centroid(2) - round(new_yrange/2), current_centroid(2) + round(new_yrange/2)]+0.5;
                if newx(1) < 0.5
                    newx = [0.5, 0.5 + round(new_xrange)];
                elseif newx(2) > app.ori_xlim(2)
                    newx = [app.ori_xlim(2)-round(new_xrange), app.ori_xlim(2)];
                end
                if newy(1) < 0.5
                    newy = [0.5, 0.5 + round(new_yrange)];
                elseif newy(2) > app.ori_ylim(2)
                    newy = [app.ori_ylim(2)-round(new_yrange), app.ori_ylim(2)];
                end
                set(app.vaxe, 'XLim', newx);
                set(app.vaxe, 'YLim', newy);
            else
                set(app.vaxe, 'XLim', app.ori_xlim);
                set(app.vaxe, 'YLim', app.ori_ylim);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadVmataviButton
        function LoadVmataviButtonPushed(app, event)
              [fname, videoPath] = uigetfile({'*.avi'; '*.mat'});
              if fname
                  figure(app.UIFigure);
                  app.ChillingLabel.Text = 'Loading...';
                  drawnow;
                  fext = strsplit(fname,'.');
                  fext = fext{end}; % file extension
                  if strcmp(fext, 'mat') % mat file
                      temp = load(strcat(videoPath, fname));
                      fn = fields(temp);
                      app.fn = fn{1}; % name of the field point to data array
                      app.vmat = squeeze(temp.(app.fn));
                  elseif strcmp(fext, 'avi')
                      vid = VideoReader(strcat(videoPath, fname));
                      temp = read(vid);
                      app.vmat = squeeze(temp);
                  else
                      error('file is invalid! please select a valid file!');
                  end
                  
                  app.or_size = size(app.vmat);
                  app.FrameSlider.Value = 1;
                  app.FrameSlider.Limits = [1, app.or_size(3)];
                  app.FrameSlider.MajorTicks = 1:round(app.or_size(3)/8):app.or_size(3);
                  app.FrameSlider.MajorTickLabels = strsplit(num2str(app.FrameSlider.MajorTicks),' ');
    %               app.vaxe.Position(4) = app.or_size(2); % set axe width and height to exact the same with 
    %               app.vaxe.Position(3) = app.or_size(1);
    %               ylim(app.vaxe, [1, app.or_size(1)]);
    %               xlim(app.vaxe, [1, app.or_size(2)]);
                  
                  app.maxproj = max(app.vmat, [], 3);
                  
                  % calculate df maxproj


                  app.bg_f = double(min(app.vmat,[], 3)); % df_f plot, bg is the median proj
                  
                  app.df_maxproj = repmat((double(app.maxproj) - app.bg_f)./app.bg_f, [1,1,3]);
                  
%                   switch app.ImagetypeDropDown.Value
%                       case 'Raw'
%                             if ~isfield(app.phandles,'vhandle')
%                                 app.phandles.vhandle = imshow(app.vmat(:,:,app.curr_frame),'Parent',app.vaxe);
%                             else
%                                 set(app.phandles.vhandle,'CData', app.vmat(:,:,app.curr_frame));
%                             end
%                             if ~isempty(app.ms)
%                                 updatems(app);
%                             else
%                                 drawnow;
%                             end
%                       case 'dF_F'                            
%                             bg_f = app.bg_f;
%                             current_f = double(app.vmat(:,:,app.curr_frame));
%                             df_frame_rgb = repmat((current_f - bg_f)./bg_f, [1,1,3]) * 4;
%                             if ~isfield(app.phandles,'vhandle')
%                                 app.phandles.vhandle = imshow(df_frame_rgb, 'Parent', app.vaxe); 
%                             else
%                                 set(app.phandles.vhandle,'CData', df_frame_rgb);
%                             end
%                                 
%                             if ~isempty(app.ms)
%                                 updatems(app);
%                             else
%                                 drawnow;
%                             end
%                       case 'MaxProj'
%                           if ~isfield(app.phandles,'vhandle')
%                                 app.phandles.vhandle = imshow(app.maxproj, 'Parent', app.vaxe);
%                           else
%                               set(app.phandles.vhandle,'CData', app.maxproj);
%                           end
%                           if ~isempty(app.ms) updatems(app); else drawnow; end
%                           
%                   end
                  updateraw(app);
                  updateraw2(app);
                  disableDefaultInteractivity(app.vaxe);
                  disableDefaultInteractivity(app.vaxe2);
                  app.ori_xlim = app.vaxe.XLim;
                  app.ori_ylim = app.vaxe.YLim;
%                   updatelabel1(app);
%                   updatelabel2(app);
                  app.ChillingLabel.Text = 'Done!';
              end
        end

        % Button pushed function: LoadmsmatButton
        function LoadmsmatButtonPushed(app, event)
            [fname, msPath] = uigetfile();
%             fprintf('loading ms mat...\n');
            app.ms_path = msPath;
            app.PathLabel.Text = strcat('Path=',msPath);
            figure(app.UIFigure);
            if fname
                app.ChillingLabel.Text = 'Loading...';
                drawnow;
                ms = load(strcat(msPath, fname));
                app.ChillingLabel.Text = 'Done!';
                fn = fields(ms);
                app.ms = getfield(ms, fn{1});
                ds = app.ms.ds;
                app.curr_cell1 = 1;
                app.curr_cell2 = 1;
                vsiz = size(app.vmat);
                SFPsiz = size(app.ms.SFPs);
                num_c = size(app.ms.SFPs, 3);
                app.num_c = num_c;
                app.dist_map = ones([num_c, num_c]) * Inf;
                app.filtcorr_map = ones([num_c, num_c]) * -2;
                app.rawcorr_map = ones([num_c, num_c]) * -2;
                app.overlap_map = ones([num_c, num_c]) * -1;
                app.t_ax = (1:size(app.ms.RawTraces,1))/app.fr;
                if ~isfield(ms,'cell_reviewed')
                    app.cell_reviewed = zeros([num_c, 1]);
                else
                    app.cell_reviewed = app.ms.cell_reviewed;
                end
                if ~isfield(app.ms, 'cell_label')
                    app.cell_label = ones([num_c, 1]);
                    app.g_count = sum(app.cell_reviewed(app.cell_label == 1) == 1);
                    app.b_count = sum(app.cell_reviewed(app.cell_label == 0) == 1);
                    app.u_count = sum(app.cell_reviewed == 0);
                    updatecount(app);
                else
                    app.cell_label = app.ms.cell_label;
                    app.g_count = sum(app.cell_reviewed(app.cell_label == 1) == 1);
                    app.b_count = sum(app.cell_reviewed(app.cell_label == 0) == 1);
                    app.u_count = sum(app.cell_reviewed == 0);
                    updatecount(app);
                end
                
                
                % get hd cell contour
%                 if any(vsiz(1:2) ~= size(app.ms.SFPs,[1,2]))   % determine if resize or crop is needed
%                     if isfield(app.ms, 'roi_pos_ds') & all(flip(vsiz(1:2)) == app.ms.roi_pos_ds(3:4)) % case 1: only cropped and no downsample
%                         roi_pos = app.ms.roi_pos_ds;
%                         ds_size = [roi_pos(4), roi_pos(3)];
%                         app.ms.SFPs = app.ms.SFPs(roi_pos(2):min(roi_pos(2)+roi_pos(4), size(app.ms.SFPs, 1)), roi_pos(1):min(size(app.ms.SFPs,2), roi_pos(1)+roi_pos(3)),:);
%                         app.A_hd = app.ms.SFPs;
%                         % at this point, SFPs are ok for downsampled video                
%                     elseif all(round(vsiz(1:2) ./ size(app.ms.CorrProj)) == ds) % this case, video is original size, only interpolation is needed
%                         
%                         [xo, yo] = meshgrid(1:app.or_size(2), 1:app.or_size(1));
%                         [xd, yd] = meshgrid(1:ds:app.or_size(2), 1:ds:app.or_size(1));
%                         A = app.ms.SFPs;
% 
%                         app.A_hd = zeros(size(xo,1),size(xo,2),size(A,3));
%                         for i = 1:num_c
%                             app.A_hd(:,:,i) = interp2(xd, yd, A(:,:,i), xo, yo);
%                         end
%                         app.A_hd(isnan(app.A_hd)) = 0;
%                     end
%                     
%                 else
%                     app.A_hd = app.ms.SFPs;
%                 end
                 if any(vsiz(1:2) ~= size(app.ms.SFPs, [1,2]))
                     
                    if all(round(vsiz(1:2)) ./ size(app.ms.SFPs, [1,2]) == ds)
                        % case 1, only resize is needed
                        A = app.ms.SFPs;
                        app.A_hd = zeros([vsiz(1:2), num_c]);
                        for i = 1:num_c
                            app.A_hd(:,:,i) = imresize(A(:,:,i), vsiz(1:2), 'method','bilinear');
                        end
                        
                    elseif and(ds == 1, isfield(app.ms, 'roi_pos_ds'))
                        % case 2, no resize, only crop
                        % assume video is raw size, SFPs are cropped
                        roi_pos = app.ms.roi_pos_ds;
                        ds_size = [roi_pos(4), roi_pos(3)];
                        if all(ds_size == size(app.ms.SFPs, [1,2]))
                            A = app.ms.SFPs;
                            app.A_hd = zeros([vsiz(1:2), num_c]);
                            crop1 = roi_pos(2) : (roi_pos(2)+roi_pos(4)-1);
                            crop2= roi_pos(1) : (roi_pos(1)+roi_pos(3)-1);
                            for i = 1:num_c
                                app.A_hd(crop1, crop2, i) = app.ms.SFPs(:,:,i);
                            end
                        else
                            error('the size of footprint does not match video. make sure correct ds and roi_pos_ds are provided');
                        end
                    elseif and(ds > 1, isfield(app.ms, 'roi_pos_ds'))
                        % case 3, resize and crop
%                         small_pos = app.ms.roi_pos_ds;
%                         raw_pos = app.ms.roi_pos_full;
%                         A = app.ms.SFPs;
%                         app.A_hd = zeros([vsiz(1:2), num_c]);
%                         crop1 = raw_pos(2):(raw_pos(2)+raw_pos(4)-1);
%                         crop2 = raw_pos(1) : (raw_pos(1)+raw_pos(3)-1);
%                         for i = 1:num_c
%                             app.A_hd(crop1, crop2, i) = imresize(app.ms.SFPs(:,:,i), [raw_pos(4), raw_pos(3)], 'method','bilinear');
%                         end
                        error('video and footprint size not match, likely because footprint was extracted from downsampled and cropped video, try manually match the size');


                    end
                     
                     
                 else
                     % case 0, SFPs match video
                     app.A_hd = app.ms.SFPs;
                 end


%                 app.A_hd_hard = app.A_hd;
%                 app.A_hd_hard(app.A_hd_hard > 0) = 1;
                
%                 calculate all centroids
                if ~isfield(app.ms, 'centroids_xz')
                   app.centroids = ConCenter(app);
                else
                    app.centroids = app.ms.centroids_xz;
                end
                if isfield(app.ms, 'FFTTraces')
                    app.TracetypeDropDown.Items = [app.TracetypeDropDown.Items, 'ROITraces'];
                end


                app.CelllistListBox1.Items = strsplit(num2str(1:num_c), ' ');
                app.CelllistListBox1.Value = app.CelllistListBox1.Items{1};
                
                app.CelllistListBox2.Items = strsplit(num2str(1:num_c), ' ');
                app.CelllistListBox2.Value = app.CelllistListBox2.Items{1};
                
                % plot all contour at vaxe2
                app.contours_h = {};
                % toggle between maxproj and df_maxproj
%                 app.ImagetypeDropDown_2.Value = 'Raw';
%                 if ~isfield(app.phandles,'vax2maxproj')
%                     app.phandles.vax2maxproj = imshow(app.vmat(:,:,app.curr_frame),'Parent',app.vaxe2);
%                 else
%                     set(app.phandles.vax2maxproj,'CData',app.vmat(:,:,app.curr_frame));
%                 end


                for i = 1:size(app.A_hd, 3)
                
                    c_thre = app.ContourThrEditField.Value;
                    to_curr_c = app.A_hd(:,:,i);
                    q = quantile(to_curr_c(to_curr_c > 0), c_thre);
                    to_curr_c = single(to_curr_c >= q);
                    if app.cell_label(i)
                        [~, tempchandle] = contour(app.vaxe2, to_curr_c, 'y', 'Tag', num2str(i),...
                            "ButtonDownFcn",@app.ContourBttnDn, 'Visible', app.ShowgoodcellsCheckBox.Value);
                    else
                        [~, tempchandle] = contour(app.vaxe2, to_curr_c, 'r', 'Tag', num2str(i),...
                            "ButtonDownFcn",@app.ContourBttnDn, 'Visible', app.ShowbadcellsCheckBox.Value);
                    end
                    app.contours_h = [app.contours_h, tempchandle];
                    
                end
                app.vaxe2.Visible = true;
                app.LockContourCheckBox.Visible = true;
                
            
                updatetrace1(app);
                updateline1(app);
                updatecontour1(app);
                updatelabel1(app);
                updatetrace2(app);
                updateline2(app);
                updatecontour2(app);
                updatelabel2(app);
                drawnow;
                  
            end
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = round(app.FrameSlider.Value);
            app.curr_frame = value;
%             app.bg_f = mean(double(app.vmat(:,:,max(app.curr_frame-75, 1):max(app.curr_frame-15,1))), 3);
            switch app.ImagetypeDropDown.Value
                  case 'Raw'
                      updateraw(app);

                      if ~isempty(app.ms)
                        updateline1(app);
                        updateline2(app); 
                      else drawnow; 
                      end

                  case 'dF_F'
                      updatedf(app);

                      if ~isempty(app.ms)
                        updateline1(app);
                        updateline2(app); 
                      else drawnow; 
                      end

                  case 'MaxProj'
%                       imshow(app.maxproj, 'Parent', app.vaxe);
                      if ~isempty(app.ms)
                        updateline1(app);
                        updateline2(app); 
                      else drawnow; 
                      end
                      
                      
            end
            switch app.ImagetypeDropDown_2.Value
                  case 'Raw'
                      updateraw2(app);
                  case 'dF_F'
                      updatedf2(app);
                  case 'MaxProj'                      
                      
            end
            app.Frame1Label.Text = ['Frame# ', num2str(value)];
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = round(event.Value);
%             app.bg_f = mean(double(app.vmat(:,:,max(app.curr_frame-75, 1):max(app.curr_frame-15,1))), 3);
            switch app.ImagetypeDropDown.Value
                  case 'Raw'
                      updateraw(app, changingValue);                     
                      if ~isempty(app.ms) 
                          updateline1(app, changingValue); 
                          updateline2(app, changingValue);
                      else drawnow; 
                      end

                  case 'dF_F'
                      updatedf(app, changingValue);                    
                      if ~isempty(app.ms) 
                          updateline1(app, changingValue);
                          updateline2(app, changingValue);
                      else drawnow; 
                      end

                  case 'MaxProj'
%                       imshow(app.maxproj, 'Parent', app.vaxe);
                      if ~isempty(app.ms) 
                          updateline1(app);
                          updateline2(app);
                      else drawnow; 
                      end
                      
            end
            switch app.ImagetypeDropDown_2.Value
                  case 'Raw'
                      updateraw2(app, changingValue);
                  case 'dF_F'
                      updatedf2(app, changingValue);
                  case 'MaxProj'
%                       imshow(app.maxproj, 'Parent', app.vaxe);                      
            end            
            app.Frame1Label.Text = ['Frame# ', num2str(changingValue)];

        end

        % Value changed function: CelllistListBox1
        function CelllistListBox1ValueChanged(app, event)
            value = str2double(app.CelllistListBox1.Value); % 
            
            app.curr_cell1 = value;
            
            updateCorr(app);
            % calculate overlap
            updateOverlap(app);
            updatetrace1(app);
            updateline1(app);
            updatecontour1(app);
            updatelabel1(app);
            updateZoom(app);
        end

        % Value changed function: CelllistListBox2
        function CelllistListBox2ValueChanged(app, event)
            value = str2double(app.CelllistListBox2.Value);
            
             app.curr_cell2 = value;
             updateCorr(app);
             updateOverlap(app);
             updatetrace2(app);
             updateline2(app);
             updatecontour2(app);
             updatelabel2(app);
                  
        end

        % Value changed function: ImagetypeDropDown
        function ImagetypeDropDownValueChanged(app, event)
            value = app.ImagetypeDropDown.Value;
            switch app.ImagetypeDropDown.Value
                  case 'Raw'
                      updateraw(app);                      
                  case 'dF_F'
                      updatedf(app);
                  case 'MaxProj'
                      set(app.phandles.vhandle, 'cData', app.maxproj);
                      
              end
            
        end

        % Value changed function: ImagetypeDropDown_2
        function ImagetypeDropDown_2ValueChanged(app, event)
            itype = app.ImagetypeDropDown_2.Value;
            switch itype
                case 'Raw'
                    updateraw2(app);
                case 'dF_F'
                    updatedf2(app);
                case 'MaxProj'
                    set(app.phandles.vax2maxproj, 'cData', app.maxproj);
            end
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'g'
                    if app.cell_reviewed(app.curr_cell1) == 1
                        app.cell_label(app.curr_cell1) = setdiff([0,1], app.cell_label(app.curr_cell1));
                    end
                    cs = {'r', 'g'};
                    badvisibleflag = app.ShowbadcellsCheckBox.Value;
                    goodvisibleflag = app.ShowgoodcellsCheckBox.Value;
                    cur_label = app.cell_label(app.curr_cell1);
                    set(app.contours_h(app.curr_cell1), 'LineColor', cs{cur_label+1}, 'Visible', 0);
                    visibleflag = or(and(cur_label, goodvisibleflag), and(~cur_label, badvisibleflag));
                    set(app.contours_h(app.curr_cell1), 'LineColor', cs{cur_label+1}, 'Visible', visibleflag);
                    app.g_count = app.g_count + cur_label*2 - 1; % if 1, return 1, if 0 return -1
                    if app.cell_reviewed(app.curr_cell1) == 0
                        app.cell_reviewed(app.curr_cell1) = 1;
                        app.u_count = app.u_count - 1;
                    else
                        app.b_count = app.b_count + cur_label*-2 + 1; % if 1, return -1, if 0 return 1
                    end
                    updatecount(app);
                    updatelabel1(app);
                case 'h'
                    if app.cell_reviewed(app.curr_cell2) == 1
                        app.cell_label(app.curr_cell2) = setdiff([0,1], app.cell_label(app.curr_cell2));
                    end
                    cs = {'r', 'g'};
                    badvisibleflag = app.ShowbadcellsCheckBox.Value;
                    goodvisibleflag = app.ShowgoodcellsCheckBox.Value;
                    cur_label = app.cell_label(app.curr_cell2);
                    set(app.contours_h(app.curr_cell1), 'LineColor', cs{cur_label+1}, 'Visible', 0);
                    visibleflag = or(and(cur_label, goodvisibleflag), and(~cur_label, badvisibleflag));
                    set(app.contours_h(app.curr_cell2), 'LineColor', cs{cur_label+1}, 'Visible', visibleflag);
                    app.g_count = app.g_count + cur_label*2 - 1; % if 1, return 1, if 0 return -1
                    if app.cell_reviewed(app.curr_cell2) == 0
                        app.cell_reviewed(app.curr_cell2) = 1;
                        app.u_count = app.u_count - 1;
                    else
                        app.b_count = app.b_count + cur_label*-2 + 1; % if 1, return -1, if 0 return 1
                    end
                    updatelabel2(app);
                    updatecount(app);
                case 'rightarrow'
                    app.curr_frame = min(app.curr_frame + app.stepEditField.Value, app.or_size(3));
                    app.FrameSlider.Value = app.curr_frame;
                    FrameSliderValueChanged(app, event);
                case 'leftarrow'
                    app.curr_frame = max(app.curr_frame - app.stepEditField.Value, 1);
                    app.FrameSlider.Value = app.curr_frame;
                    FrameSliderValueChanged(app, event);
                case 'b'
                    % cell 1 jump to max value frame
                    cid = app.curr_cell1;
                    ctrace = app.ms.RawTraces(:, cid);
                    [~, cIdx] = max(ctrace);
                    app.curr_frame = cIdx;
                    app.FrameSlider.Value = app.curr_frame;
                    FrameSliderValueChanged(app, event);
                case 'n'
                    % cell 2 jump to max value frame
                    cid = app.curr_cell2;
                    ctrace = app.ms.RawTraces(:, cid);
                    [~, cIdx] = max(ctrace);
                    app.curr_frame = cIdx;
                    app.FrameSlider.Value = app.curr_frame;
                    FrameSliderValueChanged(app, event);
                case 'j'
                    % jump to frame
                    prompt = {['Jump to frame# (1-',num2str(size(app.vmat,3)),')']};
                    dlgtitle = ['Input Frame#'];
                    input_params = inputdlg(prompt,dlgtitle);
                    jump_toF = input_params{1};
                    if ~isempty(jump_toF)
                        app.curr_frame = str2num(jump_toF);
                        app.FrameSlider.Value = app.curr_frame;
                        FrameSliderValueChanged(app,event);
                    end
                case 'i'
                    % move up cell 1
                    cur_cell1 = str2num(app.CelllistListBox1.Value);
                    app.CelllistListBox1.Value = num2str(max(cur_cell1-1, 1));
                    CelllistListBox1ValueChanged(app);
                    % sort by distance
                    Sort_ROIDistButtonPushed(app);
                    app.CelllistListBox2.Value = app.CelllistListBox2.Items{1};
                    CelllistListBox2ValueChanged(app);
                case 'k'
                    % move down cell 1
                    cur_cell1 = str2num(app.CelllistListBox1.Value);
                    app.CelllistListBox1.Value = num2str(min(cur_cell1+1, length(app.CelllistListBox1.Items)));
                    CelllistListBox1ValueChanged(app);
                    % sort by distance
                    Sort_ROIDistButtonPushed(app);
                    app.CelllistListBox2.Value = app.CelllistListBox2.Items{1};
                    CelllistListBox2ValueChanged(app);
                case 'o'
                    % move up cell 2
                    cur_cell2 = app.CelllistListBox2.Value;
                    item_idx = find(strcmp(app.CelllistListBox2.Items,cur_cell2));
                    app.CelllistListBox2.Value = app.CelllistListBox2.Items{max(1, item_idx-1)};
                    CelllistListBox2ValueChanged(app);
                case 'l'
                    % move down cell 2
                    cur_cell2 = app.CelllistListBox2.Value;
                    item_idx = find(strcmp(app.CelllistListBox2.Items,cur_cell2));
                    app.CelllistListBox2.Value = app.CelllistListBox2.Items{min(item_idx+1, length(app.CelllistListBox2.Items))};
                    CelllistListBox2ValueChanged(app);
                    
                case 'v'
                    % toggle visibility of selected ROI 1
                    if app.phandles.chandle1.Visible
                        set(app.phandles.chandle1,'Visible', 'off');
                    else
                        set(app.phandles.chandle1, 'Visible', 'on');
                    end
                case 'c'
                    % switch between different views
                    view_modes = app.ImagetypeDropDown.Items;
                    cur_modes = app.ImagetypeDropDown.Value;
                    next_mode = mod(find(strcmp(view_modes, cur_modes)) + 1, length(view_modes));
                    if next_mode == 0
                        next_mode = length(view_modes);
                    end
                    app.ImagetypeDropDown.Value = view_modes{next_mode};
                    ImagetypeDropDownValueChanged(app);
                case 'x'
                    % switch between different traces
                    trace_modes = app.TracetypeDropDown.Items;
                    cur_modes = app.TracetypeDropDown.Value;
                    next_mode = mod(find(strcmp(trace_modes, cur_modes)) + 1, length(trace_modes));
                    if next_mode == 0
                        next_mode = length(trace_modes);
                    end
                    app.TracetypeDropDown.Value = trace_modes{next_mode};
                    TracetypeDropDownValueChanged(app);
                    
            end
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            step = app.stepEditField.Value;
            ff = app.curr_frame;
            if app.PlayButton.Value app.ChillingLabel.Text = 'Playing'; else app.ChillingLabel.Text = 'Chilling...'; end
            drawnow;
            while app.PlayButton.Value & ff <= size(app.vmat,3)-step
                app.FrameSlider.Value = ff;
                FrameSliderValueChanged(app, event);
                ff = ff + step;
                drawnow
            end
            if ff >= size(app.vmat,3) - step
                app.PlayButton.Value = 0;
                app.ChillingLabel.Text = 'Chilling...';
            end
        end

        % Value changed function: TracetypeDropDown
        function TracetypeDropDownValueChanged(app, event)
            value = app.TracetypeDropDown.Value;

            
            % calculate corr and overlap
            switch app.TracetypeDropDown.Value
               case 'Raw'
                    app.corr = corr2(app.ms.RawTraces(:,app.curr_cell1), app.ms.RawTraces(:,app.curr_cell2));
               case 'Filtered'
                    app.corr = corr2(app.ms.FiltTraces(:,app.curr_cell1), app.ms.FiltTraces(:,app.curr_cell2));
               case 'ROITraces'
                    app.corr = corr2(app.ms.FFTTraces(:,app.curr_cell1), app.ms.FFTTraces(:,app.curr_cell2))
            end
            app.Corr2Label.Text = strcat('Corr: ', num2str(app.corr));


            updatetrace1(app);
            updatetrace2(app);
        end

        % Value changed function: ContourThrEditField
        function ContourThrEditFieldValueChanged(app, event)
            value = app.ContourThrEditField.Value;
            updatecontour1(app);
            updatecontour2(app);
            if ~app.LockContourCheckBox.Value
                c_thre = app.ContourThrEditField.Value;
                for i = 1:app.num_c
                    curr_c = app.A_hd(:,:,i);
                    q = quantile(curr_c(curr_c > 0), c_thre);
                    curr_c = single(curr_c >= q);
                    set(app.contours_h(i), 'ZData', curr_c);
                end
            end
        end

        % Button pushed function: Sort_ROIDistButton
        function Sort_ROIDistButtonPushed(app, event)
            
            c1 = app.curr_cell1;
            % for each, check if <Inf
            for i = 1:size(app.centroids,1)
                if app.dist_map(c1, i) == Inf
                    this_dist = sum((app.centroids(i,:) - app.centroids(c1,:)).^2);
                    app.dist_map(c1, i) = this_dist;
                    app.dist_map(i, c1) = this_dist;
                end  
            end
            % finish calculation, pull out dist list of c1
            dist_list = app.dist_map(c1,:);
            [sorted_list, sorted_ind] = sort(dist_list);
            original_items = strsplit(num2str(1:length(dist_list)), ' ');
            
            
            % only include cells that are unlabeled
%             cell2_indices = [];
%             for i = 1:size(sorted_ind)
%                 if(app.cell_reviewed(sorted_ind(i)) == 0)
%                     cell2_indices = [cell2_indices, sorted_ind(i)];
%                 end
%             end
%             
%             unreviewedID = sorted_ind(sortedID);
%             unreviewed_items = original_items(unreviewedID);
%             cell2_indices = [];
%             for i = 1:size(unreviewedID)
%                 % Find overlap
%                 cur1 = app.A_hd(:,:,app.curr_cell1);
%                 cur2 = app.A_hd(:,:,unreviewedID(i));
%                 if app.overlap_map(app.curr_cell1, unreviewedID(i)) == -1
%                     overl = (cur1.*cur2)>0;
%                     overlap = (sum(cur1(overl)) + sum(cur2(overl)))/(sum(cur1(:)) + sum(cur2(:))); 
%                     app.overlap_map(app.curr_cell1, unreviewedID(i)) = overlap;
%                     app.overlap_map(unreviewedID(i), app.curr_cell1) = overlap;
%                 else
%                     overlap = app.overlap_map(app.curr_cell1, unreviewedID(i));
%                 end
%                 
%                 if overlap < 0.01
%                     continue;
%                 end
%                 % Find correlation
%                 if app.rawcorr_map(app.curr_cell1, unreviewedID(i)) == -2
%                     corr = corr2(app.ms.RawTraces(:, app.curr_cell1), app.ms.RawTraces(:, unreviewedID(i)));
%                     app.rawcorr_map(app.curr_cell1,  unreviewedID(i)) = app.corr;
%                     app.rawcorr_map(unreviewedID(i), app.curr_cell1) = app.corr;
%                 else
%                     corr = app.rawcorr_map(app.curr_cell1, unreviewedID(i));
%                 end
%                 % Keep corr > 0 and overlap > 0 
%                 if corr > 0.05
%                     cell2_indices = [cell2_indices, unreviewedID(i)];
%                 end
%             end
%             
%             app.CelllistListBox2.Items = unreviewed_items(cell2_indices);
             app.CelllistListBox2.Items = original_items(sorted_ind);
            drawnow;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.ms.cell_label = app.cell_label;
            app.ms.centroids_xz = app.centroids;
            app.ms.cell_reviewed = app.cell_reviewed;
            [file,name,path] = uiputfile([app.ms_path,'\ms.mat']);
            figure(app.UIFigure);
            ms = app.ms;
            if path
                app.ChillingLabel.Text = 'Saving...';
                drawnow;
                save(strcat(name, file), 'ms');
                app.ChillingLabel.Text = 'Done!';
                pause(3);
                app.ChillingLabel.Text = 'Chilling';
                drawnow;
            end
        end

        % Button pushed function: RasterVideoButton
        function RasterVideoButtonPushed(app, event)
            [file, name, path] = uiputfile([pwd,'\*.avi'], 'File Selection', 'ms_raster.avi');
            params.filename = file;
            params.path = name;
            prompt = {'Step size:','Num of frames to write:'};
            dlgtitle = 'Input params';
            definput = {'10','all'};
            input_params = inputdlg(prompt,dlgtitle,[1,15],definput);
            params.stepsize = str2double(input_params{1});
            if strcmp(input_params{2}, 'all')
                params.f2w = 0; % raster all
            else
                params.f2w = str2double(input_params{2}); % raste first input number of frames
            end
            if path
                app.ChillingLabel.Text = 'Busy...';
                RasterMo(app, params);
                msgbox('Video finished!');
                app.ChillingLabel.Text = 'Chilling...';
            end
            
        end

        % Value changed function: ShowbadcellsCheckBox
        function ShowbadcellsCheckBoxValueChanged(app, event)
            showbadflag = app.ShowbadcellsCheckBox.Value;
            badID = find(app.cell_label == 0);
                          
            for i = 1:length(badID)
                if(app.cell_reviewed(badID(i)) == 1)
                    set(app.contours_h(badID(i)), 'LineColor', 'r' , 'Visible', showbadflag);
                end
            end

        end

        % Value changed function: ShowgoodcellsCheckBox
        function ShowgoodcellsCheckBoxValueChanged(app, event)
            showgoodflag = app.ShowgoodcellsCheckBox.Value;
            goodID = find(app.cell_label == 1);             
            for i = 1:length(goodID)
                if(app.cell_reviewed(goodID(i)) == 1)
                    set(app.contours_h(goodID(i)), 'LineColor', 'g', 'Visible', showgoodflag);
                end
            end

        end

        % Button pushed function: Sort_ROIValButton
        function Sort_ROIValButtonPushed(app, event)
            rtraces = app.ms.RawTraces;
            cur_val = rtraces(round(app.curr_frame), :);
            [~, sorted_ind] = sort(cur_val, 'descend');
            original_items = strsplit(num2str(1:size(rtraces,2)), ' ');
            app.CelllistListBox2.Items = original_items(sorted_ind);
            drawnow;
            
        end

        % Button pushed function: ROITraceButton
        function ROITraceButtonPushed(app, event)
            app.ms = FFTTraces(app.vmat, app.ms, app.ContourThrEditField.Value, false);
            if ~any(strcmp(app.TracetypeDropDown.Items, 'ROITraces'))
                app.TracetypeDropDown.Items = [app.TracetypeDropDown.Items, 'ROITraces'];
            end
        end

        % Button pushed function: ClearmsButton
        function ClearmsButtonPushed(app, event)
            answer = questdlg('Clear ms?','Clear ms', 'Yes',...
                'No','No');
            switch answer
                case 'Yes'
                    app.ms = [];
                    app.A_hd = [];
                    app.curr_cell1 = 1;
                    app.curr_cell2 = 1;
                    app.cell_label = [];
                    app.centroids = [];
                    app.dist_map = [];
                    app.rawcorr_map = [];
                    app.overlap_map = [];
                    app.filtcorr_map = [];
                    app.num_c = [];
                    app.ms_path = [];
                    app.g_count = [];
                    app.b_count = [];
                    app.u_count = [];
                    for i = 1:length(app.contours_h)
                        delete(app.contours_h(i));
                    end
                    app.contours_h = {};
                    pnames = fields(app.phandles);
                    for i = 1:length(pnames)
                        if ~any(strcmp({'vhandle', 'vax2maxproj'}, pnames{i}))
                            delete(app.phandles.(pnames{i}));
                            app.phandles = rmfield(app.phandles, pnames(i));
                        end
                    end
                        
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            answer = questdlg('Close app? Make sure your file is saved.', ...
	        'Close CellScreener', ...
	        'Yes','No','No');
            switch answer
                case 'Yes'
                    delete(app)
                case 'No'
                    % do nothing
            
            end
            
        end

        % Value changed function: AutozoomEditField
        function AutozoomEditFieldValueChanged(app, event)
            updateZoom(app);
            
        end

        % Value changed function: ShowunlabeledcellsCheckBox
        function ShowunlabeledcellsCheckBoxValueChanged(app, event)
            showunlabeledflag = app.ShowunlabeledcellsCheckBox.Value;
            unlabeledID = find(app.cell_reviewed == 0);             
            for i = 1:length(unlabeledID)
                set(app.contours_h(unlabeledID(i)), 'LineColor', 'w', 'Visible', showunlabeledflag);
            end
        end

        % Value changed function: MaxTraceThrEditField
        function MaxTraceThreshEditFieldValueChanged(app, event)
            value = app.MaxTraceThrEditField.Value;
            for i = 1:size(app.ms.RawTraces, 2)
                if max(app.ms.RawTraces(:, i)) < value
                    app.cell_label(i) = 0;
                    app.cell_reviewed(i) = 1;
                else
                    app.cell_label(i) = 1;
                    app.cell_reviewed(i) = 0;
                end
            end
            ShowbadcellsCheckBoxValueChanged(app,event);
            ShowunlabeledcellsCheckBoxValueChanged(app,event);
            updatecount(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.vaxe = uiaxes(app.UIFigure);
            title(app.vaxe, 'Miniscope')
            xlabel(app.vaxe, '')
            ylabel(app.vaxe, '')
            app.vaxe.XTick = [];
            app.vaxe.YTick = [];
            app.taxe1 = uiaxes(app.UIFigure);
            title(app.taxe1, '')
            xlabel(app.taxe1, '')
            ylabel(app.taxe1, '')
            app.taxe2 = uiaxes(app.UIFigure);
            title(app.taxe2, '')
            xlabel(app.taxe2, 'Time/s')
            ylabel(app.taxe2, '')
            app.vaxe2 = uiaxes(app.UIFigure);
            title(app.vaxe2, 'MaxProj')
            xlabel(app.vaxe2, '')
            ylabel(app.vaxe2, '')
            app.vaxe2.XTick = [];
            app.vaxe2.YTick = [];
            app.vaxe2.Visible = 'off';
            app.LoadVmataviButton = uibutton(app.UIFigure, 'push');
            app.LoadVmataviButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVmataviButtonPushed, true);
            app.LoadVmataviButton.Text = 'Load V(mat/avi)';
            app.LoadmsmatButton = uibutton(app.UIFigure, 'push');
            app.LoadmsmatButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmsmatButtonPushed, true);
            app.LoadmsmatButton.Text = 'Load ms.mat';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.MajorTicks = [0 100];
            app.FrameSlider.MajorTickLabels = {'0', '100'};
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.MinorTicks = [];
            app.ImagetypeDropDownLabel = uilabel(app.UIFigure);
            app.ImagetypeDropDownLabel.Text = 'Image type';
            app.ImagetypeDropDown = uidropdown(app.UIFigure);
            app.ImagetypeDropDown.Items = {'MaxProj', 'Raw', 'dF_F'};
            app.ImagetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagetypeDropDownValueChanged, true);
            app.ImagetypeDropDown.Value = 'Raw';
            app.Celllist1ListBoxLabel = uilabel(app.UIFigure);
            app.Celllist1ListBoxLabel.Text = 'Cell list 1';
            app.CelllistListBox1 = uilistbox(app.UIFigure);
            app.CelllistListBox1.ValueChangedFcn = createCallbackFcn(app, @CelllistListBox1ValueChanged, true);
            app.GoodLabel1 = uilabel(app.UIFigure);
            app.GoodLabel1.Text = 'Good';
            app.stepEditFieldLabel = uilabel(app.UIFigure);
            app.stepEditFieldLabel.Text = 'step';
            app.stepEditField = uieditfield(app.UIFigure, 'numeric');
            app.stepEditField.Limits = [1 Inf];
            app.stepEditField.Value = 30;
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.ChillingLabel = uilabel(app.UIFigure);
            app.ChillingLabel.Text = 'Chilling...';
            app.TracetypeDropDownLabel = uilabel(app.UIFigure);
            app.TracetypeDropDownLabel.Text = 'Trace type';
            app.TracetypeDropDown = uidropdown(app.UIFigure);
            app.TracetypeDropDown.Items = {'Raw', 'Filtered'};
            app.TracetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TracetypeDropDownValueChanged, true);
            app.TracetypeDropDown.Value = 'Raw';
            app.ContourThrEditFieldLabel = uilabel(app.UIFigure);
            app.ContourThrEditFieldLabel.Text = 'Contour Thr';
            app.ContourThrEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContourThrEditField.ValueChangedFcn = createCallbackFcn(app, @ContourThrEditFieldValueChanged, true);
            app.ContourThrEditField.Value = 0.5;
            app.Sort_ROIDistButton = uibutton(app.UIFigure, 'push');
            app.Sort_ROIDistButton.ButtonPushedFcn = createCallbackFcn(app, @Sort_ROIDistButtonPushed, true);
            app.Sort_ROIDistButton.Tooltip = {'Sort ROIs by their distances to cell 1'};
            app.Sort_ROIDistButton.Text = 'Sort_ROI (Dist)';
            app.Corr2Label = uilabel(app.UIFigure);
            app.Corr2Label.Text = {'Corr: -2'; ''};
            app.Overlap0Label = uilabel(app.UIFigure);
            app.Overlap0Label.Text = 'Overlap: 0';
            app.CelllistListBoxLabel_2 = uilabel(app.UIFigure);
            app.CelllistListBoxLabel_2.Text = 'Cell list 2';
            app.CelllistListBox2 = uilistbox(app.UIFigure);
            app.CelllistListBox2.ValueChangedFcn = createCallbackFcn(app, @CelllistListBox2ValueChanged, true);
            app.GoodLabel2 = uilabel(app.UIFigure);
            app.GoodLabel2.Text = 'Good';
            app.Trace1Label = uilabel(app.UIFigure);
            app.Trace1Label.Text = {'Trace 1'; ''};
            app.Trace2Label = uilabel(app.UIFigure);
            app.Trace2Label.Text = {'Trace 2'; ''};
            app.LockContourCheckBox = uicheckbox(app.UIFigure);
            app.LockContourCheckBox.Visible = 'off';
            app.LockContourCheckBox.Text = 'LockContour';
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'Path=';
            app.RasterVideoButton = uibutton(app.UIFigure, 'push');
            app.RasterVideoButton.ButtonPushedFcn = createCallbackFcn(app, @RasterVideoButtonPushed, true);
            app.RasterVideoButton.Text = {'RasterVideo'; ''};
            app.Goodcount0Label = uilabel(app.UIFigure);
            app.Goodcount0Label.Text = 'Good count = 0';
            app.Badcount0Label = uilabel(app.UIFigure);
            app.Badcount0Label.Text = 'Bad count = 0';
            app.ShowbadcellsCheckBox = uicheckbox(app.UIFigure);
            app.ShowbadcellsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowbadcellsCheckBoxValueChanged, true);
            app.ShowbadcellsCheckBox.Text = 'Show bad cells';
            app.ImagetypeDropDown_2 = uidropdown(app.UIFigure);
            app.ImagetypeDropDown_2.Items = {'MaxProj', 'Raw', 'dF_F'};
            app.ImagetypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ImagetypeDropDown_2ValueChanged, true);
            app.ImagetypeDropDown_2.Value = 'Raw';
            app.ShowgoodcellsCheckBox = uicheckbox(app.UIFigure);
            app.ShowgoodcellsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowgoodcellsCheckBoxValueChanged, true);
            app.ShowgoodcellsCheckBox.Text = 'Show good cells';
            app.Sort_ROIValButton = uibutton(app.UIFigure, 'push');
            app.Sort_ROIValButton.ButtonPushedFcn = createCallbackFcn(app, @Sort_ROIValButtonPushed, true);
            app.Sort_ROIValButton.Tooltip = {'Sort ROIs by their value at current frame'};
            app.Sort_ROIValButton.Text = 'Sort_ROI (Val)';
            app.Frame1Label = uilabel(app.UIFigure);
            app.Frame1Label.Text = 'Frame#1';
            app.ROITraceButton = uibutton(app.UIFigure, 'push');
            app.ROITraceButton.ButtonPushedFcn = createCallbackFcn(app, @ROITraceButtonPushed, true);
            app.ROITraceButton.Text = 'ROI Trace';
            app.ClearmsButton = uibutton(app.UIFigure, 'push');
            app.ClearmsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearmsButtonPushed, true);
            app.ClearmsButton.Text = 'Clear ms';
            app.AutozoomEditFieldLabel = uilabel(app.UIFigure);
            app.AutozoomEditFieldLabel.Text = 'Auto zoom';
            app.AutozoomEditField = uieditfield(app.UIFigure, 'numeric');
            app.AutozoomEditField.Limits = [1 Inf];
            app.AutozoomEditField.ValueDisplayFormat = '%.0f';
            app.AutozoomEditField.ValueChangedFcn = createCallbackFcn(app, @AutozoomEditFieldValueChanged, true);
            app.AutozoomEditField.Value = 1;
            app.KeyPressFcnSsortbydistanceBLabel = uilabel(app.UIFigure);
            app.KeyPressFcnSsortbydistanceBLabel.Text = {'KeyPressFcn:'; 'G/H -- toggle GOOD/BAD of cell1/2'; 'S -- sort by distance'; 'I,K -- cell1 go up/down'; 'O,L -- cell2 go up/down'; 'B,N -- jump to peak of cell1/2'; 'V -- toggle visibility of cell1 ROI'; 'C -- switch image type mode'; 'LeftArrow/RightArrow -- previous/next step number of frames'; 'J -- jump to frame#'};
            app.ShowunlabeledcellsCheckBox = uicheckbox(app.UIFigure);
            app.ShowunlabeledcellsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowunlabeledcellsCheckBoxValueChanged, true);
            app.ShowunlabeledcellsCheckBox.Text = 'Show unlabeled cells';
            app.Unlabeledcount0Label = uilabel(app.UIFigure);
            app.Unlabeledcount0Label.Text = 'Unlabeled count = 0';
            app.MaxTraceThrEditFieldLabel = uilabel(app.UIFigure);
            app.MaxTraceThrEditFieldLabel.Text = 'Max Trace Thr';
            app.MaxTraceThrEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxTraceThrEditField.Limits = [0 Inf];
            app.MaxTraceThrEditField.ValueDisplayFormat = '%.0f';
            app.MaxTraceThrEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTraceThreshEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellScreenerNaren

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end