classdef Noise_Maker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Button             matlab.ui.control.Button
        noiseLamp          matlab.ui.control.Lamp
        StatusLamp_2Label  matlab.ui.control.Label
        toneLamp           matlab.ui.control.Lamp
        StatusLampLabel    matlab.ui.control.Label
        WhiteNoiseButton   matlab.ui.control.StateButton
        kHzToneButton      matlab.ui.control.StateButton
        NoiseMakerv1Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        P = struct; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% param initialization
                app.toneLamp.Color = 'red';
                app.noiseLamp.Color = 'red';
            %% create white noise
                app.P.Fs_noise = 44100;
                Fs_noise = 44100;                                                     % Sampling Frequency (Hz)
                dur = 60; %s, duration of noise
                fstart = 5000; % hz, filter start
                fstop = 10000; % hz, filter stop
                fcuts = [fstart-100  fstart  fstop  fstop+100];                 % Frequency Vector (Hz)
                mags =   [0 1 0];                                               % Magnitude (Defines Passbands & Stopbands)
                devs = [0.05  0.01  0.05];                                      % Allowable Deviations
                [n,Wn,beta,ftype] = kaiserord(fcuts,mags,devs,Fs_noise);
                n = n + rem(n,2);
                hh = fir1(n,Wn,ftype,kaiser(n+1,beta),'scale');
                s = randn(1, dur*Fs_noise);                                             % Gaussian White Noise
                %St = 0 : 1/Fswn : (length(s)-1)/Fswn;
                app.P.y_noise = filtfilt(hh, 1, s);                                     % Filter Signal 

            %% create 4kHz pure tone
                tone_freq = 4000; % hz of tone
                app.P.Fs_tone = 44100;  % sampling freq (hz) NOTE: tone limit is 22 kHz
                Fs_tone = 44100;
                Ts = 1/Fs_tone;  % sampling interval (s)
                T = 0:Ts:(Fs_tone*Ts*dur);
                app.P.y_tone = sin(2*pi*tone_freq*T); % tone

          
        end

        % Value changed function: kHzToneButton
        function kHzToneButtonValueChanged(app, event)
            tone_value = app.kHzToneButton.Value;
          
        persistent player_tone
          
          if isempty(player_tone)
              
             player_tone = audioplayer(app.P.y_tone, app.P.Fs_tone);
             %set(handles.playLOOP,'string','STOP');
             play(player_tone);
          else
             stop(player_tone)
             %set(handles.playLOOP,'string','PLAY');
             player_tone = [];
          end
        
        % adjust lamp indicator
        if tone_value == 0
            app.toneLamp.Color = 'red';
        elseif tone_value == 1
            app.toneLamp.Color = 'green';
        end


        end

        % Value changed function: WhiteNoiseButton
        function WhiteNoiseButtonValueChanged(app, event)
            noise_value = app.WhiteNoiseButton.Value;
          
            persistent player_noise
          
          if isempty(player_noise)
             player_noise = audioplayer(app.P.y_noise, app.P.Fs_noise);
             play(player_noise);
          else
             stop(player_noise)
             player_noise = [];
          end

            if noise_value == 0
                app.noiseLamp.Color = 'red';
            elseif noise_value == 1
                app.noiseLamp.Color = 'green';
            end


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            msgbox({'Press button to start sound.'; 'Press again to stop sound'; 'Red light indicates sound is off.'; 'Green light indicates sound on.'; 'ZZ 20220817'});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NoiseMakerv1Label = uilabel(app.UIFigure);
            app.NoiseMakerv1Label.Text = 'Noise Maker v1';
            app.kHzToneButton = uibutton(app.UIFigure, 'state');
            app.kHzToneButton.ValueChangedFcn = createCallbackFcn(app, @kHzToneButtonValueChanged, true);
            app.kHzToneButton.Text = '4 kHz Tone';
            app.WhiteNoiseButton = uibutton(app.UIFigure, 'state');
            app.WhiteNoiseButton.ValueChangedFcn = createCallbackFcn(app, @WhiteNoiseButtonValueChanged, true);
            app.WhiteNoiseButton.Text = 'White Noise';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.toneLamp = uilamp(app.UIFigure);
            app.StatusLamp_2Label = uilabel(app.UIFigure);
            app.StatusLamp_2Label.Text = 'Status';
            app.noiseLamp = uilamp(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Noise_Maker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end